classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GradientTab                    matlab.ui.container.Tab
        LaplacianTab                   matlab.ui.container.Tab
        LaplacianVersionDropDown       matlab.ui.control.DropDown
        LaplacianVersionDropDownLabel  matlab.ui.control.Label
        LaplacianMaskDimensionEditField  matlab.ui.control.NumericEditField
        MaskDimensionEditFieldLabel    matlab.ui.control.Label
        LaplacianSigmaEditField        matlab.ui.control.NumericEditField
        SigmaEditFieldLabel            matlab.ui.control.Label
        LaplacianThresholdEditField    matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel        matlab.ui.control.Label
        LaplacianTransformButton       matlab.ui.control.Button
        LaplacianBrowseButton          matlab.ui.control.Button
        LaplacianTechniqueDropDown     matlab.ui.control.DropDown
        TechniqueDropDownLabel         matlab.ui.control.Label
        LaplacianBasedMethodLabel      matlab.ui.control.Label
        LaplacianEdgeAxes              matlab.ui.control.UIAxes
        LaplacianSegmentAxes           matlab.ui.control.UIAxes
        LaplacianOriAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inputImageAxesArr % Array to store all input image axes
        edgeImageAxesArr % Array to store all edge detection result axes
        segmentImageAxesArr % Array to store all object segmentation result axes
        laplacianFname % Filename that used in Laplacian Tab
        gradientFnam % Filename that used in Gradient Tab
    end
    
    methods (Access = private)
        
        function initiateImageAxesComponent(~, component)
            component.Visible = 'off';
            component.Colormap = gray(256);
            axis(component, 'image');
        end
        
        function updateImage(app, fname, tab_num)
            % Read the image
            try
                im = imread(fname);
            catch ME
                % if problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            % assign current tab input
            currInputImageAxes = app.inputImageAxesArr(tab_num);

            % assign current tab edge
            currEdgeImageAxes = app.edgeImageAxesArr(tab_num);
            
            % assign current tab segmented object
            currSegmentImageAxes = app.segmentImageAxesArr(tab_num);    

            % display the image
            imagesc(currInputImageAxes, im);

            % create histograms based on number of color channel
            switch size(im,3)
                case 1
                    % Display the grayscale image

                    imgOut = getOutput(app, im, tab_num);
                    imagesc(currOutAxes, imgOut);

                case 3
                    imgOut(:,:,1) = getOutput(app, im(:,:,1), tab_num);
                    imgOut(:,:,2) = getOutput(app, im(:,:,2), tab_num);
                    imgOut(:,:,3) = getOutput(app, im(:,:,3), tab_num);
                    imagesc(currOutAxes, imgOut);
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end 
        end
        
        function imgOut = getOutput(app, im, tab_num)
            switch (tab_num)
                case 1
                    % Code here
                case 2
                    % Code here
                otherwise
                    % Error when the tab is undefined
                    uialert(app.UIFigure, 'Tab invalid.', 'Image Error');
                    return;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initiate the image axes
            app.inputImageAxesArr = [app.LaplacianOriAxes];
            app.edgeImageAxesArr = [app.LaplacianEdgeAxes];
            app.segmentImageAxesArr = [app.LaplacianSegmentAxes];

            % image path
            path = [pwd filesep '..' filesep 'images' filesep];

            % Show loading dialog
            d = uiprogressdlg(app.UIFigure, 'Title', 'Segmenting image...', 'Indeterminate', 'on');
            drawnow

            %% Gradient

            %% Laplacian
            initiateImageAxesComponent(app, app.LaplacianOriAxes);
            initiateImageAxesComponent(app, app.LaplacianEdgeAxes);
            initiateImageAxesComponent(app, app.LaplacianSegmentAxes);
            app.laplacianFname = [path 'buah.jpg'];
            if strcmp(app.LaplacianTechniqueDropDown.Value, 'Laplacian')
                app.LaplacianSigmaEditField.Visible = "off"; % Hide sigma
                app.LaplacianMaskDimensionEditField = "off"; % Hide mask dimension
            elseif strcmp(app.LaplacianTechniqueDropDown.Value, 'Laplacian of Gaussian')
                app.LaplacianVersionDropDown.Visible = "off"; % Hide Laplacian version dropdown
            end
            

            % Stop loading dialog
            close(d);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GradientTab = uitab(app.TabGroup);
            app.GradientTab.Title = 'Gradient';
            app.LaplacianTab = uitab(app.TabGroup);
            app.LaplacianTab.Title = 'Laplacian';
            app.LaplacianOriAxes = uiaxes(app.LaplacianTab);
            title(app.LaplacianOriAxes, 'Original Image')
            app.LaplacianOriAxes.XTick = [];
            app.LaplacianOriAxes.YTick = [];
            app.LaplacianOriAxes.ZTick = [];
            app.LaplacianSegmentAxes = uiaxes(app.LaplacianTab);
            title(app.LaplacianSegmentAxes, 'Segmentation Result')
            app.LaplacianSegmentAxes.XTick = [];
            app.LaplacianSegmentAxes.YTick = [];
            app.LaplacianSegmentAxes.ZTick = [];
            app.LaplacianEdgeAxes = uiaxes(app.LaplacianTab);
            title(app.LaplacianEdgeAxes, 'Edge Detection Result')
            app.LaplacianEdgeAxes.XTick = [];
            app.LaplacianEdgeAxes.YTick = [];
            app.LaplacianEdgeAxes.ZTick = [];
            app.LaplacianBasedMethodLabel = uilabel(app.LaplacianTab);
            app.LaplacianBasedMethodLabel.Text = 'Laplacian-Based Method';
            app.TechniqueDropDownLabel = uilabel(app.LaplacianTab);
            app.TechniqueDropDownLabel.Text = 'Technique';
            app.LaplacianTechniqueDropDown = uidropdown(app.LaplacianTab);
            app.LaplacianTechniqueDropDown.Items = {'Laplacian', 'Laplacian of Gaussian'};
            app.LaplacianTechniqueDropDown.Value = 'Laplacian';
            app.LaplacianBrowseButton = uibutton(app.LaplacianTab, 'push');
            app.LaplacianBrowseButton.Text = 'Browse';
            app.LaplacianTransformButton = uibutton(app.LaplacianTab, 'push');
            app.LaplacianTransformButton.Text = 'Transform';
            app.ThresholdEditFieldLabel = uilabel(app.LaplacianTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.LaplacianThresholdEditField = uieditfield(app.LaplacianTab, 'numeric');
            app.LaplacianThresholdEditField.Limits = [1 254];
            app.LaplacianThresholdEditField.RoundFractionalValues = 'on';
            app.LaplacianThresholdEditField.Value = 32;
            app.SigmaEditFieldLabel = uilabel(app.LaplacianTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.LaplacianSigmaEditField = uieditfield(app.LaplacianTab, 'numeric');
            app.LaplacianSigmaEditField.Value = 1.2;
            app.MaskDimensionEditFieldLabel = uilabel(app.LaplacianTab);
            app.MaskDimensionEditFieldLabel.Text = 'Mask Dimension';
            app.LaplacianMaskDimensionEditField = uieditfield(app.LaplacianTab, 'numeric');
            app.LaplacianMaskDimensionEditField.Limits = [1 Inf];
            app.LaplacianMaskDimensionEditField.RoundFractionalValues = 'on';
            app.LaplacianMaskDimensionEditField.Value = 3;
            app.LaplacianVersionDropDownLabel = uilabel(app.LaplacianTab);
            app.LaplacianVersionDropDownLabel.Text = 'Laplacian Version';
            app.LaplacianVersionDropDown = uidropdown(app.LaplacianTab);
            app.LaplacianVersionDropDown.Items = {'Original', 'Diagonal'};
            app.LaplacianVersionDropDown.Value = 'Original';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end